classdef NicotineApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NicotinePKPDUIFigure            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DrugReceptorParametersPanel     matlab.ui.container.Panel
        ChaperoningkknMLabel            matlab.ui.control.Label
        FractionofChaperonednAChRSlider  matlab.ui.control.Slider
        ActivationkknMLabel             matlab.ui.control.Label
        FractionofActivatednAChRSlider  matlab.ui.control.Slider
        DropDown                        matlab.ui.control.DropDown
        DosingSchemeDropDown            matlab.ui.control.DropDown
        DosingSchemeDropDownLabel       matlab.ui.control.Label
        DistributionandEliminationParametersPanel  matlab.ui.container.Panel
        VdlitersSlider                  matlab.ui.control.Slider
        VdlitersSliderLabel             matlab.ui.control.Label
        kr1hrSlider                     matlab.ui.control.Slider
        kr1hrLabel                      matlab.ui.control.Label
        kf1hrSlider                     matlab.ui.control.Slider
        kf1hrSliderLabel                matlab.ui.control.Label
        kel1hrSlider                    matlab.ui.control.Slider
        kel1hrSliderLabel               matlab.ui.control.Label
        IngestionscigarettesorvapesessionsPanel  matlab.ui.container.Panel
        MolecularWeightEditField        matlab.ui.control.NumericEditField
        MolecularWeightEditFieldLabel   matlab.ui.control.Label
        DosingAmountUnitField_4         matlab.ui.control.Label
        DosingAmountUnitField_3         matlab.ui.control.Label
        DosingAmountField_2             matlab.ui.control.NumericEditField
        DosingAmountUnitField_2         matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        DailyingestionsSlider           matlab.ui.control.Slider
        DailyingestionsSliderLabel      matlab.ui.control.Label
        DosingAmountField               matlab.ui.control.NumericEditField
        DosingAmountSlider              matlab.ui.control.Slider
        NicotinedoseLabel               matlab.ui.control.Label
        DosingIntervalField             matlab.ui.control.Spinner
        IngestionintervalLabel          matlab.ui.control.Label
        DurationofsimulationField       matlab.ui.control.NumericEditField
        DurationofsimulationEditField_3Label  matlab.ui.control.Label
        DurationofsimulationUnitField   matlab.ui.control.Label
        DosingAmountUnitField           matlab.ui.control.Label
        DosingIntervalUnitField         matlab.ui.control.Label
        DefaultParametersButton         matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PlotsvsTimeTab                  matlab.ui.container.Tab
        AxesConc                        matlab.ui.control.UIAxes
        Axes                            matlab.ui.control.UIAxes
        OutputDataTable                 matlab.ui.container.Tab
        SaveDataTableButton             matlab.ui.control.Button
        SimDataTable                    matlab.ui.control.Table
        ExplanationTab                  matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
        InputDoseTable                  matlab.ui.container.Tab
        ReadfromCSVButton               matlab.ui.control.Button
        NumberofDosesEditField          matlab.ui.control.NumericEditField
        NumberofDosesEditFieldLabel     matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)

        Kel0(1,1) double        % original kel value in model
        Kf0(1,1) double        % original kf value in model
        Kr0(1,1) double        % original kr value in model
        Vd0(1,1) double        % original vd value in model
        FAnAChR0(1,1) double
        FCnAChR0(1,1) double
        Duration0(1,1) double   % original duration of simulation
        Amount0(1,1) double     % original dosing amount
        Interval0(1,1) double   % original dosing interval
        TotalDaily0(1,1) double
        MolecularWeight0(1,1) double
        
        DoseTable cell         % daily dose to apply to simulate
        SimFun                  % exported simFunction 
        ConcColors       = [0.30,0.75,0.93;...
                            0.86,0.55,0.41;...
                            0.91,0.73,0.42]; % colors to plot concentrations
        ConcStyle        = {'-';'-';'-'};
    end

    methods (Access = private)
    
        function sd = simulateModel(app)
            % retrieve simulation settings from App
            kel = app.kel1hrSlider.Value / 60;
            kf = app.kf1hrSlider.Value / 60;
            kr = app.kr1hrSlider.Value / 60;
            vd = app.VdlitersSlider.Value;
            fanAChR = app.FractionofActivatednAChRSlider.Value;
            fcnAChR = app.FractionofChaperonednAChRSlider.Value;
            stopTime = app.DurationofsimulationField.Value;
            
            app.DoseTable{1}.Amount = app.DosingAmountField.Value;
            app.DoseTable{2}.Amount = app.DosingAmountField.Value;
            app.DoseTable{1}.Interval = app.DosingIntervalField.Value;
            app.DoseTable{2}.Interval = app.DosingIntervalField.Value;
            app.DoseTable{1}.RepeatCount = app.EditField.Value - 1;
            app.DoseTable{2}.RepeatCount = app.EditField.Value - 1;
            % simulate model using SimFunction
            %sd = app.SimFun([kel,0.025,0.02,0], stopTime, app.DoseTable);
            %sd = app.SimFun([kel,kf,kr], stopTime, app.DoseTable);
            if app.DosingSchemeDropDown.Value ~= "User Input"
                sd = app.SimFun([kel,kf,kr,vd,fanAChR, fcnAChR, (vd / 200.0) * 60.0, (vd / 200.0) * 140.0], stopTime, app.DoseTable);
            else
                n = app.NumberofDosesEditField.Value;
                uidata = app.UITable.Data;
                %disp(uidata)
                %disp(uidata(1:3))
                %disp(uidata(4:6))
                times = uidata(1:n);
                amounts = uidata(n+1:2*n) * (1.0 / (app.MolecularWeightEditField.Value / 1000.0));
                dt = cell(1,2);
                d = table(times', amounts');
                %d = table(zeros(n,2));
                %disp(d)
                %%for i = 1:n
                %%    d(i,1) = uidata(i,1);
                %%    d(i,2) = uidata(i,2);
                    %d = table(uidata(i),uidata(n + i));
                %    d.Properties.VariableNames = {'Time','Amount'};
                %    d.Properties.VariableUnits = {'second','miligram'};
                %    dt{i} = d;
                %    dt{n + i} = d_other;
                %%end
                d.Properties.VariableNames = {'Time','Amount'};
                d.Properties.VariableUnits = {'second','micromole'};
                %disp(d)
                dt{1} = d;
                d_other = table(0,0);
                d_other.Properties.VariableNames = {'Time','Amount'};
                d_other.Properties.VariableUnits = {'second','micromole'};
                dt{2} = d_other;
                %disp('Created dose tables')
                %disp(dt)
                sd = app.SimFun([kel,kf,kr,vd,fanAChR, fcnAChR, (vd / 200.0) * 60.0, (vd / 200.0) * 140.0], stopTime, dt);
            end
            %sd = app.SimFun([kel,kf,kr,500,fanAChR], stopTime, app.DoseTable);
            
        end
        
        function updateApp(app)
            
            % TODO: update only lines in plots, not whole plots
            
            % create progress dialog
            d = uiprogressdlg(app.NicotinePKPDUIFigure,'Message','Simulating model & updating App ...','Indeterminate','on');
            
            try
                % simulate model
                
                sd = simulateModel(app);
                
                yyaxis(app.Axes,'right');
                app.Axes.YLim = [1000, 1000000];
                set(app.Axes,'YColor','k');
                set(app.Axes,'YScale','log');
                ylabel(app.Axes,'Concentration (ng/mL)')
                
                yyaxis(app.Axes,'left');
                set(app.Axes,'YColor','k');
                
                % create table containing simulation results
                t = array2table([sd.time, sd.Data], 'VariableNames',[{'Time'}; sd.DataNames]);
                
                % update plots 
                xlim(app.Axes,'auto');
                xlim(app.AxesConc,'auto');
                ylim(app.Axes,'auto');
                ylim(app.AxesConc,'auto');
                
                semilogy(app.Axes, t.Time, t.("Plasma/ CSF/ ER Nicotine"), 'Color', [0.4940 0.1840 0.5560], 'Linewidth',2);
                hold(app.Axes,'on');
                plot(app.Axes, t.Time, t.("Sequestered Nicotine"), 'Color', [0.3010 0.7450 0.9330], 'Linewidth',2);
                
                app.Axes.YLim = [1, 1000];
                app.Axes.XLim = [0, app.DurationofsimulationField.Value];
                lg = legend(app.Axes, {'Plasma/ CSF Nicotine', 'Sequestered Nicotine'});
                lg.TextColor = ones(1,3)*0.4;
                lg.Box = 'off';
                hold(app.Axes,'off');
                
                plot(app.AxesConc, t.Time, t.("Fraction of Activated nAChR"), app.ConcStyle{1},'Linewidth',2,'Color', [0.4660 0.6740 0.1880]);
                hold(app.AxesConc,'on');
                plot(app.AxesConc, t.Time, t.("Fraction of Chaperoned nAChR"), app.ConcStyle{2},'Linewidth',2,'Color',[0.8500 0.3250 0.0980]);
                hold(app.AxesConc,'off');
                app.AxesConc.YLim = [0, 1];
                app.AxesConc.XLim = [0, app.DurationofsimulationField.Value];
                lh = legend(app.AxesConc,{'Activated nAChR channels','Chaperoned nAChR protein'});
                %lh = legend(app.AxesConc,{'Activated MOR'});
                lh.TextColor = ones(1,3)*0.4;
                lh.Box = 'off';
                
                % update table
                app.SimDataTable.Data = t;
                units = [{'hours'}; cellfun(@(x) x.Units, sd.DataInfo, 'UniformOutput', false)];
                app.SimDataTable.ColumnName = string(t.Properties.VariableNames') + " (" + string(units) + ")";
                
                %app.SimDataTable.Data = removevars(t,{'Fraction of Chaperoned nAChR'});
                app.SimDataTable.ColumnName = [{'Time (hours)'};
                        {'Plasma/ CSF Nicotine (nanomolarity)'};
                        {'Sequestered Nicotine (nanomolarity)'};
                        {'Activated nAChR channels (dimensionless)'};
                        {'Chaperoned nAChR protein (dimensionless)'}];
                % disp(app.SimDataTable.ColumnName)
                              
                % close progress dialog
                d.Indeterminate = 'off';
                d.Value = 1;
                
            catch e
                disp(e.identifier)
                disp(e.message)
                d.Indeterminate = 'off';
                d.Value = 1;
                d.Icon = 'error';
                d.Message = 'An error has occurred!';
                pause(2);
            end
            
            close(d);
        end
        
        function setDefaultValues(app)
            app.kel1hrSlider.Value = app.Kel0;
            app.kf1hrSlider.Value = app.Kf0;
            app.kr1hrSlider.Value = app.Kr0;
            app.VdlitersSlider.Value = app.Vd0;
            app.FractionofActivatednAChRSlider.Value = app.FAnAChR0;
            app.FractionofChaperonednAChRSlider.Value = app.FCnAChR0;
            app.DurationofsimulationField.Value = app.Duration0;
            app.DosingAmountField.Value = app.Amount0;
            app.DosingAmountSlider.Value = app.Amount0;
            app.DosingAmountField_2.Value = app.Amount0 * (app.MolecularWeight0 / 1000.0);
            app.DosingIntervalField.Value = app.Interval0;
            app.DailyingestionsSlider.Value = app.TotalDaily0;
            app.EditField.Value = app.TotalDaily0;
            app.MolecularWeightEditField.Value = app.MolecularWeight0;
        end
        
        function setMouseDefaultValues(app)
            app.kel1hrSlider.Value = 2.0 * app.Kel0;
            app.kf1hrSlider.Value = 2.0 * app.Kf0;
            app.kr1hrSlider.Value = 2.0 * app.Kr0;
            app.VdlitersSlider.Value = app.Vd0 / 2.0;
            app.FractionofActivatednAChRSlider.Value = app.FAnAChR0;
            app.FractionofChaperonednAChRSlider.Value = app.FCnAChR0;
            app.DurationofsimulationField.Value = app.Duration0;
            app.DosingAmountField.Value = app.Amount0;
            app.DosingAmountSlider.Value = app.Amount0;
            app.DosingAmountField_2.Value = app.Amount0 * (app.MolecularWeight0 / 1000.0);
            app.DosingIntervalField.Value = app.Interval0;
            app.DailyingestionsSlider.Value = app.TotalDaily0;
            app.EditField.Value = app.TotalDaily0;
            app.MolecularWeightEditField.Value = app.MolecularWeight0;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HTML.HTMLSource = '<p><b><big>About the simulation</big></b></p><p><b><i>Legend to the simulation</i></b></p><p>The plots give simulations of nicotine pharmacokinetics and pharmacodynamics during smoking. At left, the pharmacokinetic / pharmacodynamic model, implemented in Matlab SimBiology. Individual parameters and structures, and smoking dosages are presented in Supplemental OutputDataTable 2 and in the Supplemental ZIP File of (Shivange et al., 2019).</p><p>The "reset" button reverts the simulation to the “standard event”. This is a common pattern of smoking: 1 cigarette, yielding 1 mg of ingested nicotine, each h, for 12 h during each day, elimination rate constant kel 0.706 /h.</p><p>At right, top. Nicotine concentrations in the plasma / CSF / ER, and in the “sequestered” compartment. The “sequestered” compartment was termed the “peripheral compartment” by (Benowitz et al., 1991), but that terminology is less preferable in discussions of the nervous system. Note the logarithmic [nicotine] scale.</p><p>At right, bottom. Effects on the two processes shown at left. Note that the “standard habit” activates nicotinic acetylcholine receptor (nAChR) protein chaperoning (inside-out process) > 50%, but this activates nicotinic acetylcholine receptor (nAChR) channel activation (outside-in process) < 20%.</p><p>This app is a slightly modified, interactive version of Figure 8 in (Shivange et al, 2019). The original simulations were performed by Janice Jeon, then a Caltech undergrad, in the Matlab SimBiology Toolbox. In 2020, Dr. Fulden Fulden Buyukozturk (The Mathworks) adapted the simulations to a Matlab Web App, with advice from Suresh Balakrishnama of The Mathworks. At Caltech, Alexander Wang, a Caltech undergrad, installed the app on a Windows 10 and modified the user interface further, based on suggestions from Henry Lester''s research group.</p><p><b><i>Lightning scientific summary: subcellular and macroscopic pharmacokinetics of nicotine.</i></b></p><p>Modified from the abstract of (Shivange et al., 2019). Nicotine dependence is thought to arise in part because nicotine permeates into the endoplasmic reticulum (ER), where it binds to nAChRs and begins an “inside-out” pathway that leads to upregulation of nAChRs on the plasma membrane. However until 2019, the dynamics of nicotine entry into the ER were not quantified. This problem was solved with a series of genetically-encoded fluorescent biosensors for nicotine, termed iNicSnFRs. In several cell types, we find that nicotine equilibrates in the ER within 10 s (possibly within 1 s) of extracellular application, and leaves as rapidly after removal from the extracellular solution. The [nicotine] in the ER is within two-fold of the extracellular value.</p><p>The app uses these data to run combined pharmacokinetic and pharmacodynamic simulations of human smoking. In the ER, the inside-out pathway begins when nicotine becomes a stabilizing pharmacological chaperone for some nAChR subtypes, even at concentrations as low as ~ 10 nM. Such concentrations would persist during the 12 h of a typical smoker;s day, continually activating the inside-out pathway by > 75%. Reducing nicotine intake by 10-fold decreases activation to ~ 20%.</p><p><b><i>Additional details: the outside-in and inside-out pathways during smoking</i></b></p><p>The data (Shivange et al., 2019) generated two insights important for understanding the inside-out pathway. 1, The genetically encoded nicotine biosensors targeted to the ER reveal that nicotine appears in the ER within ≤ 10 s after it appears in the extracellular solution. The delay may be as small as 1 s. 2, After this delay, the [nicotine] in the ER differs by less than two-fold from [nicotine] in the extracellular solution.</p><p>The most complete data on pharmacological chaperoning have utilized extracellular application of nicotine, applied for several h (Kuryatov et al., 2005). Therefore, a major question arising from previous data was whether nicotine enters the ER quickly enough to serve as a pharmacological chaperone. The answer, based on (Shivange et al., 2019), is clearly, “yes.” When nicotine is removed from the extracellular solution, nicotine leaves the ER, again within 10 s. If [nicotine] in the ER drops below the EC50 for pharmacological chaperoning during the interval between cigarettes, then the inside-out pathway cannot readily account for nicotine dependence. We term this point the “rapid exit” problem.</p><p>The simulation addresses the “rapid exit” problem by using existing data on the pharmacokinetics of nicotine during smoking. After a person receives a bolus of nicotine from a cigarette or an electronic nicotine delivery system (ENDS), [nicotine] in the body decreases with two exponential terms. During the slower phase, measurable nicotine endures in the plasma for several h (Benowitz et al., 1991). The app simulates fractional activation of the inside-out and outside-in pathways using the available data from the literature (Benowitz et al., 1991; Kuryatov et al., 2005; Rollema et al., 2007).</p><p>Because of the highly nicotine-sensitive feature of pharmacological chaperoning, [nicotine] in the ER remains greater than the EC50 for pharmacological chaperoning during the entire 1 h interval between cigarettes in the “standard habit”. The inside-out pathway remains > 50% activated continually during the 12 h of the “standard habit” (lower panel). We concluded that the inside-out pathway, operating via pharmacological chaperoning, can readily account for upregulation, an important component of nicotine dependence.</p><p><b><i>''Acute,'' ''repeated'' and ''chronic'' exposure to nicotine</i></b></p><p>(Shivange, 2019) remarked, “Our simulations also lead one to re-examine the relationship between the previously distinct concepts of ''acute'', ''repeated'' and ''chronic'' exposure to nicotine. In the ''standard habit,'' even a single cigarette, whose nicotine is fully ingested during 10 min, activates the inside-out pathway > 50% until the next cigarette one h later. Thus, a series of ''acute'' and ''repeated'' exposures, one/hr for 12 h, becomes ''chronic'' activation for the 12 h of smoking activity. By the next morning, nicotine levels have decreased below levels that activate pharmacological chaperoning; but the downstream trafficking and subsequent sequelae, including nAChR upregulation, probably endure for several days (Marks et al., 1985).</p><p>“Thus, subcellular pharmacokinetics readily explains dependence on smoked nicotine. Around the world at any time, several hundred million people have ingested tobacco within the past h; they retain nicotine in every organelle of every cell. In the ER and cis-Golgi of the small percentage of cells that contain α4β2 nAChRs, these levels activate the inside-out pathway > 50%, and therefore maintain one aspect of nicotine dependence.”</p><p><b><i>Quantitative aspects of reducing nicotine levels</i></b></p><p>Shivange at also noted, “the prominence of the inside-out pathway in nicotine dependence has prompted us to simulate the activation of the inside-out pathway, as well as the outside-in pathway, during use of modified tobacco or electronic nicotine delivery system (ENDS) (Table 1 of Shivange, 2019). United States FDA officials recently suggested decreasing the nicotine content of combustible tobacco. Our simulations show that a three-fold reduction would nonetheless provide > 40% average activation of the inside-out pathway; a ten-fold reduction (to 0.1 mg/cigarette) would reduce average activation to 20%. This conclusion would also hold for ingestion via an edlectronic nicotinic delivery system.”</p><p><b><i>Fast and slow metabolizers: the “elimination parameter”</i></b></p><p>The “standard habit” simulates a “typical” smoker (Benowitz et al., 1991), summarized by the “elimination parameter”. Increasing the “elimination parameter”, decreases the lifetime of nicotine in the blood / CSF / ER compartment. Polymorphisms in cytochrome P450 2A6, the enzyme that primarily metabolizes nicotine, lead to dramatic (up to 10-fold) differences in nicotine lifetime among people (Dempsey et al., 2004). Fast and slow metabolizers display differences in the frequency of smoking (Tanner et al., 2015). Future investigations must simulate the consequences of these variations for activation of the inside-out pathway.</p><p><b><i>Funding</i></b></p><p>This research and the development of this app was supported by grants from US National Institutes of Health (DA036061, DA037161, DA043829, GM123582, GM007616, MH061876, NS097362, and NS034407), the California Tobacco-Related Disease Research Project (23XT-0007), the California Institute for Regenerative Medicine (EDUC2-08398), the Brain and Behavior Research Foundation (NARSAD), the Howard Hughes Medical Institute, and the Della Martin Foundation. Edwin Chapman is an Investigator of the Howard Hughes Medical Institute.</p><p><b><i>References Cited</i></b></p><p>Benowitz, N.L., P. Jacob, 3rd, C. Denaro, and R. Jenkins. 1991. Stable isotope studies of nicotine kinetics and bioavailability. <i>Clin Pharmacol Ther.</i> 49:270-277.</p><p>Dempsey, D., P. Tutka, P. Jacob, 3rd, F. Allen, K. Schoedel, R.F. Tyndale, and N.L. Benowitz. 2004. Nicotine metabolite ratio as an index of cytochrome P450 2A6 metabolic activity. <i>Clin Pharmacol Ther.</i> 76:64-72.</p><p>Kuryatov, A., J. Luo, J. Cooper, and J. Lindstrom. 2005. Nicotine acts as a pharmacological chaperone to up-regulate human a4b2 acetylcholine receptors. <i>Mol Pharmacol.</i> 68:1839-1851.</p><p>Marks, M.J., J.A. Stitzel, and A.C. Collins. 1985. Time course study of the effects of chronic nicotine infusion on drug response and brain receptors. <i>J Pharmacol Exp Ther.</i> 235:619-628.</p><p>Rollema, H., L.K. Chambers, J.W. Coe, J. Glowa, R.S. Hurst, L.A. Lebel, Y. Lu, R.S. Mansbach, R.J. Mather, C.C. Rovetti, S.B. Sands, E. Schaeffer, D.W. Schulz, F.D. Tingley, 3rd, and K.E. Williams. 2007. Pharmacological profile of the α4β2 nicotinic acetylcholine receptor partial agonist varenicline, an effective smoking cessation aid. <i>Neuropharmacology.</i> 52.</p><p>Shivange, A.V., P.M. Borden, A.K. Muthusamy, A.L. Nichols, K. Bera, H. Bao, I. Bishara, J. Jeon, M.J. Mulcahy, B. Cohen, S.L. ORiordan, C. Kim, D.A. Dougherty, E.R. Chapman, J.S. Marvin, L.L. Looger, and H.A. Lester. 2019. Determining the pharmacokinetics of nicotinic drugs in the endoplasmic reticulum using biosensors. <i>J Gen Physiol 151:738-757.</i> <a href=""https://rupress.org/jgp/article/151/6/738/121017/Determining-the-pharmacokinetics-of-nicotinic"">https://rupress.org/jgp/article/151/6/738/121017/Determining-the-pharmacokinetics-of-nicotinic</a></p><p>Tanner, J.A., M.J. Chenoweth, and R.F. Tyndale. 2015. Pharmacogenetics of nicotine and associated smoking behaviors. <i>Current topics in behavioral neurosciences.</i> 23:37-86.</p>';
            
            % save model and dose as App properties
            load('simFunction_Nicotine.mat', 'simFun', 'doseTable');
            app.SimFun    = simFun;
            app.DoseTable = doseTable;
            
            % save original values to allow for resetting
            app.Kel0 = app.SimFun.Parameters.Value(1);
            app.Kf0 = app.SimFun.Parameters.Value(2);
            app.Kr0 = app.SimFun.Parameters.Value(3);
            app.Vd0 = 200;
            %app.Vd0 = app.SimFun.Parameters.Value(4);
            %app.FAnAChR0 = app.SimFun.Parameters.Value(5);
            app.FAnAChR0 = 1000;
            app.FCnAChR0 = app.SimFun.Parameters.Value(6);
            %app.Kon0 = app.SimFun.Parameters.Value(2);
            app.Duration0 = 50;
            %app.Rate0 = app.DoseTable.Rate;
            app.Interval0 = app.DoseTable{1}.Interval;
            app.Amount0   = app.DoseTable{1}.Amount;
            app.TotalDaily0 = 12;
            app.MolecularWeight0 = 162;
            
            % update UI with model values
            setDefaultValues(app);
            
            % run simulation and update app
            updateApp(app);
            
            % modify plot style
            %graystyle(app.Axes);
            %graystyle(app.AxesConc);
            
            % make tables and plots visible
            app.Axes.Visible = true;
            app.AxesConc.Visible = true; 
            app.SimDataTable.Visible = true;
            
            % format user dose table
            app.UITable.ColumnFormat = {'short' 'bank'};
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NicotinePKPDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1050, 1050};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {529, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: kel1hrSlider
        function kel1hrSliderValueChanged(app, event)
            updateApp(app);
        end

        % Callback function
        function DosingIntervalFieldValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: DurationofsimulationField
        function DurationofsimulationFieldValueChanged(app, event)
            updateApp(app);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            updateApp(app);
        end

        % Value changed function: DosingAmountSlider
        function DosingAmountSliderValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: DosingAmountField
        function DosingAmountFieldValueChanged(app, event)
            app.DosingAmountSlider.Value = app.DosingAmountField.Value;
            app.DosingAmountField_2.Value = app.DosingAmountField.Value * (app.MolecularWeightEditField.Value / 1000.0);
            updateApp(app);
        end

        % Value changing function: DosingAmountSlider
        function DosingAmountSliderValueChanging(app, event)
            app.DosingAmountField.Value = round(event.Value);
            app.DosingAmountField_2.Value = app.DosingAmountField.Value * (app.MolecularWeightEditField.Value / 1000.0);
        end

        % Button pushed function: DefaultParametersButton
        function DefaultParametersButtonPushed(app, event)
            if(app.DropDown.Value == 'Human')
                setDefaultValues(app);
            else
                setMouseDefaultValues(app);
            end
            updateApp(app);
        end

        % Value changed function: DailyingestionsSlider
        function DailyingestionsSliderValueChanged(app, event)
            updateApp(app)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.DailyingestionsSlider.Value = app.EditField.Value;
            updateApp(app)
        end

        % Value changing function: DailyingestionsSlider
        function DailyingestionsSliderValueChanging(app, event)
            app.EditField.Value = round(event.Value);
        end

        % Value changed function: kf1hrSlider
        function kf1hrSliderValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: kr1hrSlider
        function kr1hrSliderValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: VdlitersSlider
        function VdlitersSliderValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: FractionofActivatednAChRSlider
        function FractionofActivatednAChRSliderValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: MolecularWeightEditField
        function MolecularWeightEditFieldValueChanged(app, event)
            value = app.MolecularWeightEditField.Value;
            app.DosingAmountField_2.Value = app.DosingAmountField.Value * (value / 1000.0);
            updateApp(app);
        end

        % Value changed function: DosingAmountField_2
        function DosingAmountField_2ValueChanged(app, event)
            value = app.DosingAmountField_2.Value;
            app.DosingAmountField.Value = value * (1.0 / (app.MolecularWeightEditField.Value / 1000.0));
            app.DosingAmountSlider.Value = value * (1.0 / (app.MolecularWeightEditField.Value / 1000.0));
            updateApp(app);
        end

        % Value changed function: DosingSchemeDropDown
        function DosingSchemeDropDownValueChanged(app, event)
            value = app.DosingSchemeDropDown.Value;
            %disp(value);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            %disp(indices)
            %disp(newData)
            %disp(app.UITable.Data)
            %disp(app.UITable.Data)
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
        end

        % Value changed function: NumberofDosesEditField
        function NumberofDosesEditFieldValueChanged(app, event)
            n = app.NumberofDosesEditField.Value;
            % Make this not delete existing values
            dt = zeros([n,2],'double');
            uidata = app.UITable.Data;
            n_prev = size(app.UITable.Data,1);
            if n < n_prev
                dt(1:n) = uidata(1:n);
                dt(n+1:2*n) = uidata(n_prev+1:n_prev+n);
            else
                dt(1:n_prev) = uidata(1:n_prev);
                dt(n+1:n+n_prev) = uidata(n_prev+1:2*n_prev);
            end
            app.UITable.Data = dt;
        end

        % Value changed function: FractionofChaperonednAChRSlider
        function FractionofChaperonednAChRSliderValueChanged(app, event)
            %value = app.FractionofChaperonednAChRSlider.Value;
            updateApp(app);
        end

        % Button pushed function: SaveDataTableButton
        function SaveDataTableButtonPushed(app, event)
            [FileName,PathName] = uiputfile('nicotineapp_40_datatable.csv','Save table data');
            writetable(app.SimDataTable.Data,[PathName,FileName])
        end

        % Button pushed function: ReadfromCSVButton
        function ReadfromCSVButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            x = readmatrix(fullfile(path, file));
            app.UITable.Data = x;
            app.NumberofDosesEditField.Value = size(x,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NicotinePKPDUIFigure = uifigure('Visible', 'off');
            app.NicotinePKPDUIFigure.Name = 'Nicotine PKPD';
            app.NicotinePKPDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.NicotinePKPDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = 'nicotine_diagram_new.svg';
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Icon = 'Run_24.png';
            app.SimulateButton.Text = 'Simulate';
            app.DefaultParametersButton = uibutton(app.LeftPanel, 'push');
            app.DefaultParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultParametersButtonPushed, true);
            app.DefaultParametersButton.Text = 'Default Parameters';
            app.IngestionscigarettesorvapesessionsPanel = uipanel(app.LeftPanel);
            app.IngestionscigarettesorvapesessionsPanel.Title = 'Ingestions: cigarettes or vape sessions';
            app.DosingIntervalUnitField = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.DosingIntervalUnitField.Text = 'minute';
            app.DosingAmountUnitField = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.DosingAmountUnitField.Text = 'μmol';
            app.DurationofsimulationUnitField = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.DurationofsimulationUnitField.Text = 'hour';
            app.DurationofsimulationEditField_3Label = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.DurationofsimulationEditField_3Label.Text = 'Duration of simulation';
            app.DurationofsimulationField = uieditfield(app.IngestionscigarettesorvapesessionsPanel, 'numeric');
            app.DurationofsimulationField.UpperLimitInclusive = 'off';
            app.DurationofsimulationField.Limits = [1 Inf];
            app.DurationofsimulationField.ValueChangedFcn = createCallbackFcn(app, @DurationofsimulationFieldValueChanged, true);
            app.DurationofsimulationField.Value = 50;
            app.IngestionintervalLabel = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.IngestionintervalLabel.Text = 'Ingestion interval';
            app.DosingIntervalField = uispinner(app.IngestionscigarettesorvapesessionsPanel);
            app.DosingIntervalField.Step = 10;
            app.DosingIntervalField.Limits = [4 1440];
            app.DosingIntervalField.RoundFractionalValues = 'on';
            app.DosingIntervalField.Value = 60;
            app.NicotinedoseLabel = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.NicotinedoseLabel.Text = 'Nicotine dose';
            app.DosingAmountSlider = uislider(app.IngestionscigarettesorvapesessionsPanel);
            app.DosingAmountSlider.Limits = [0 20];
            app.DosingAmountSlider.MajorTicks = [0 5 10 15 20];
            app.DosingAmountSlider.ValueChangedFcn = createCallbackFcn(app, @DosingAmountSliderValueChanged, true);
            app.DosingAmountSlider.ValueChangingFcn = createCallbackFcn(app, @DosingAmountSliderValueChanging, true);
            app.DosingAmountSlider.Value = 6.5;
            app.DosingAmountField = uieditfield(app.IngestionscigarettesorvapesessionsPanel, 'numeric');
            app.DosingAmountField.Limits = [0 300];
            app.DosingAmountField.ValueDisplayFormat = '%.1f';
            app.DosingAmountField.ValueChangedFcn = createCallbackFcn(app, @DosingAmountFieldValueChanged, true);
            app.DosingAmountField.Value = 6;
            app.DailyingestionsSliderLabel = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.DailyingestionsSliderLabel.Text = 'Daily ingestions';
            app.DailyingestionsSlider = uislider(app.IngestionscigarettesorvapesessionsPanel);
            app.DailyingestionsSlider.Limits = [0 40];
            app.DailyingestionsSlider.ValueChangedFcn = createCallbackFcn(app, @DailyingestionsSliderValueChanged, true);
            app.DailyingestionsSlider.ValueChangingFcn = createCallbackFcn(app, @DailyingestionsSliderValueChanging, true);
            app.DailyingestionsSlider.Value = 16;
            app.EditField = uieditfield(app.IngestionscigarettesorvapesessionsPanel, 'numeric');
            app.EditField.Limits = [0 40];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 16;
            app.DosingAmountUnitField_2 = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.DosingAmountUnitField_2.Text = 'mg';
            app.DosingAmountField_2 = uieditfield(app.IngestionscigarettesorvapesessionsPanel, 'numeric');
            app.DosingAmountField_2.Limits = [0 300];
            app.DosingAmountField_2.ValueDisplayFormat = '%.3f';
            app.DosingAmountField_2.ValueChangedFcn = createCallbackFcn(app, @DosingAmountField_2ValueChanged, true);
            app.DosingAmountField_2.Value = 0.972;
            app.DosingAmountUnitField_3 = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.DosingAmountUnitField_3.Text = 'ingestions';
            app.DosingAmountUnitField_4 = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.DosingAmountUnitField_4.Text = 'g/mol';
            app.MolecularWeightEditFieldLabel = uilabel(app.IngestionscigarettesorvapesessionsPanel);
            app.MolecularWeightEditFieldLabel.Text = 'Molecular Weight';
            app.MolecularWeightEditField = uieditfield(app.IngestionscigarettesorvapesessionsPanel, 'numeric');
            app.MolecularWeightEditField.Limits = [0 1000];
            app.MolecularWeightEditField.ValueChangedFcn = createCallbackFcn(app, @MolecularWeightEditFieldValueChanged, true);
            app.MolecularWeightEditField.Value = 162;
            app.DistributionandEliminationParametersPanel = uipanel(app.LeftPanel);
            app.DistributionandEliminationParametersPanel.Title = 'Distribution and Elimination Parameters';
            app.kel1hrSliderLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.kel1hrSliderLabel.Text = 'kel (1/hr)';
            app.kel1hrSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.kel1hrSlider.Limits = [0 0.2];
            app.kel1hrSlider.MajorTicks = [0 0.04 0.08 0.12 0.16 0.2];
            app.kel1hrSlider.ValueChangedFcn = createCallbackFcn(app, @kel1hrSliderValueChanged, true);
            app.kel1hrSlider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2];
            app.kel1hrSlider.Value = 0.12;
            app.kf1hrSliderLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.kf1hrSliderLabel.Text = 'kf (1/hr)';
            app.kf1hrSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.kf1hrSlider.Limits = [0 0.2];
            app.kf1hrSlider.MajorTicks = [0 0.04 0.08 0.12 0.16 0.2];
            app.kf1hrSlider.ValueChangedFcn = createCallbackFcn(app, @kf1hrSliderValueChanged, true);
            app.kf1hrSlider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2];
            app.kr1hrLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.kr1hrLabel.Text = 'kr (1/hr)';
            app.kr1hrSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.kr1hrSlider.Limits = [0 0.2];
            app.kr1hrSlider.MajorTicks = [0 0.04 0.08 0.12 0.16 0.2];
            app.kr1hrSlider.ValueChangedFcn = createCallbackFcn(app, @kr1hrSliderValueChanged, true);
            app.kr1hrSlider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2];
            app.VdlitersSliderLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.VdlitersSliderLabel.Text = 'Vd (liters)';
            app.VdlitersSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.VdlitersSlider.Limits = [1 1000];
            app.VdlitersSlider.MajorTicks = [1 200 400 600 800 1000];
            app.VdlitersSlider.MajorTickLabels = {'1', '200', '400', '600', '800', '1000'};
            app.VdlitersSlider.ValueChangedFcn = createCallbackFcn(app, @VdlitersSliderValueChanged, true);
            app.VdlitersSlider.MinorTicks = [1 40 80 120 160 200 240 280 320 360 400 440 480 520 560 600 640 680 720 760 800 840 880 920 960 1000];
            app.VdlitersSlider.Value = 200;
            app.DosingSchemeDropDownLabel = uilabel(app.LeftPanel);
            app.DosingSchemeDropDownLabel.Text = 'Dosing Scheme';
            app.DosingSchemeDropDown = uidropdown(app.LeftPanel);
            app.DosingSchemeDropDown.Items = {'Fixed Interval', 'User Input'};
            app.DosingSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @DosingSchemeDropDownValueChanged, true);
            app.DosingSchemeDropDown.Value = 'Fixed Interval';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Human', 'Rat'};
            app.DropDown.Value = 'Human';
            app.DrugReceptorParametersPanel = uipanel(app.LeftPanel);
            app.DrugReceptorParametersPanel.Title = 'Drug-Receptor Parameters';
            app.FractionofActivatednAChRSlider = uislider(app.DrugReceptorParametersPanel);
            app.FractionofActivatednAChRSlider.Limits = [1 3000];
            app.FractionofActivatednAChRSlider.MajorTicks = [1 1000 2000 3000];
            app.FractionofActivatednAChRSlider.ValueChangedFcn = createCallbackFcn(app, @FractionofActivatednAChRSliderValueChanged, true);
            app.FractionofActivatednAChRSlider.Value = 1000;
            app.ActivationkknMLabel = uilabel(app.DrugReceptorParametersPanel);
            app.ActivationkknMLabel.Text = 'Activation k-/k+ (nM)';
            app.FractionofChaperonednAChRSlider = uislider(app.DrugReceptorParametersPanel);
            app.FractionofChaperonednAChRSlider.Limits = [1 100];
            app.FractionofChaperonednAChRSlider.MajorTicks = [1 20 40 60 80 100];
            app.FractionofChaperonednAChRSlider.ValueChangedFcn = createCallbackFcn(app, @FractionofChaperonednAChRSliderValueChanged, true);
            app.FractionofChaperonednAChRSlider.MinorTicks = [1 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.FractionofChaperonednAChRSlider.Value = 37;
            app.ChaperoningkknMLabel = uilabel(app.DrugReceptorParametersPanel);
            app.ChaperoningkknMLabel.Text = 'Chaperoning k-/k+ (nM)';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotsvsTimeTab = uitab(app.TabGroup);
            app.PlotsvsTimeTab.Title = 'Plots vs. Time';
            app.Axes = uiaxes(app.PlotsvsTimeTab);
            xlabel(app.Axes, 'Time (hours)')
            ylabel(app.Axes, 'Concentration (nM)')
            app.AxesConc = uiaxes(app.PlotsvsTimeTab);
            xlabel(app.AxesConc, 'Time (hours)')
            ylabel(app.AxesConc, 'Fraction')
            app.OutputDataTable = uitab(app.TabGroup);
            app.OutputDataTable.Title = 'Output Data Table';
            app.SimDataTable = uitable(app.OutputDataTable);
            app.SimDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SimDataTable.RowName = {};
            app.SaveDataTableButton = uibutton(app.OutputDataTable, 'push');
            app.SaveDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataTableButtonPushed, true);
            app.SaveDataTableButton.Text = 'Save Data Table';
            app.ExplanationTab = uitab(app.TabGroup);
            app.ExplanationTab.Title = 'Explanation';
            app.HTML = uihtml(app.ExplanationTab);
            app.HTML.HTMLSource = '''<p><b><big>About the simulation</big></b></p><p><b><i>Legend to the simulation</i></b></p><p>The plots give simulations of nicotine pharmacokinetics and pharmacodynamics during smoking. At left, the pharmacokinetic / pharmacodynamic model, implemented in Matlab SimBiology. Individual parameters and structures, and smoking dosages are presented in Supplemental OutputDataTable 2 and in the Supplemental ZIP File of (Shivange et al., 2019).</p><p>The "reset" button reverts the simulation to the “standard event”. This is a common pattern of smoking: 1 cigarette, yielding 1 mg of ingested nicotine, each h, for 12 h during each day, elimination rate constant kel 0.706 /h.</p><p>At right, top. Nicotine concentrations in the plasma / CSF / ER, and in the “sequestered” compartment. The “sequestered” compartment was termed the “peripheral compartment” by (Benowitz et al., 1991), but that terminology is less preferable in discussions of the nervous system. Note the logarithmic [nicotine] scale.</p><p>At right, bottom. Effects on the two processes shown at left. Note that the “standard habit” activates nicotinic acetylcholine receptor (nAChR) protein chaperoning (inside-out process) > 50%, but this activates nicotinic acetylcholine receptor (nAChR) channel activation (outside-in process) < 20%.</p><p>This app is a slightly modified, interactive version of Figure 8 in (Shivange et al, 2019). The original simulations were performed by Janice Jeon, then a Caltech undergrad, in the Matlab SimBiology Toolbox. In 2020, Dr. Fulden Fulden Buyukozturk (The Mathworks) adapted the simulations to a Matlab Web App, with advice from Suresh Balakrishnama of The Mathworks. At Caltech, Alexander Wang, a Caltech undergrad, installed the app on a Windows 10 and modified the user interface further, based on suggestions from Henry Lester''''s research group.</p><p><b><i>Lightning scientific summary: subcellular and macroscopic pharmacokinetics of nicotine.</i></b></p><p>Modified from the abstract of (Shivange et al., 2019). Nicotine dependence is thought to arise in part because nicotine permeates into the endoplasmic reticulum (ER), where it binds to nAChRs and begins an “inside-out” pathway that leads to upregulation of nAChRs on the plasma membrane. However until 2019, the dynamics of nicotine entry into the ER were not quantified. This problem was solved with a series of genetically-encoded fluorescent biosensors for nicotine, termed iNicSnFRs. In several cell types, we find that nicotine equilibrates in the ER within 10 s (possibly within 1 s) of extracellular application, and leaves as rapidly after removal from the extracellular solution. The [nicotine] in the ER is within two-fold of the extracellular value.</p><p>The app uses these data to run combined pharmacokinetic and pharmacodynamic simulations of human smoking. In the ER, the inside-out pathway begins when nicotine becomes a stabilizing pharmacological chaperone for some nAChR subtypes, even at concentrations as low as ~ 10 nM. Such concentrations would persist during the 12 h of a typical smoker;s day, continually activating the inside-out pathway by > 75%. Reducing nicotine intake by 10-fold decreases activation to ~ 20%.</p><p><b><i>Additional details: the outside-in and inside-out pathways during smoking</i></b></p><p>The data (Shivange et al., 2019) generated two insights important for understanding the inside-out pathway. 1, The genetically encoded nicotine biosensors targeted to the ER reveal that nicotine appears in the ER within ≤ 10 s after it appears in the extracellular solution. The delay may be as small as 1 s. 2, After this delay, the [nicotine] in the ER differs by less than two-fold from [nicotine] in the extracellular solution.</p><p>The most complete data on pharmacological chaperoning have utilized extracellular application of nicotine, applied for several h (Kuryatov et al., 2005). Therefore, a major question arising from previous data was whether nicotine enters the ER quickly enough to serve as a pharmacological chaperone. The answer, based on (Shivange et al., 2019), is clearly, “yes.” When nicotine is removed from the extracellular solution, nicotine leaves the ER, again within 10 s. If [nicotine] in the ER drops below the EC50 for pharmacological chaperoning during the interval between cigarettes, then the inside-out pathway cannot readily account for nicotine dependence. We term this point the “rapid exit” problem.</p><p>The simulation addresses the “rapid exit” problem by using existing data on the pharmacokinetics of nicotine during smoking. After a person receives a bolus of nicotine from a cigarette or an electronic nicotine delivery system (ENDS), [nicotine] in the body decreases with two exponential terms. During the slower phase, measurable nicotine endures in the plasma for several h (Benowitz et al., 1991). The app simulates fractional activation of the inside-out and outside-in pathways using the available data from the literature (Benowitz et al., 1991; Kuryatov et al., 2005; Rollema et al., 2007).</p><p>Because of the highly nicotine-sensitive feature of pharmacological chaperoning, [nicotine] in the ER remains greater than the EC50 for pharmacological chaperoning during the entire 1 h interval between cigarettes in the “standard habit”. The inside-out pathway remains > 50% activated continually during the 12 h of the “standard habit” (lower panel). We concluded that the inside-out pathway, operating via pharmacological chaperoning, can readily account for upregulation, an important component of nicotine dependence.</p><p><b><i>''''Acute,'''' ''''repeated'''' and ''''chronic'''' exposure to nicotine</i></b></p><p>(Shivange, 2019) remarked, “Our simulations also lead one to re-examine the relationship between the previously distinct concepts of ''''acute'''', ''''repeated'''' and ''''chronic'''' exposure to nicotine. In the ''''standard habit,'''' even a single cigarette, whose nicotine is fully ingested during 10 min, activates the inside-out pathway > 50% until the next cigarette one h later. Thus, a series of ''''acute'''' and ''''repeated'''' exposures, one/hr for 12 h, becomes ''''chronic'''' activation for the 12 h of smoking activity. By the next morning, nicotine levels have decreased below levels that activate pharmacological chaperoning; but the downstream trafficking and subsequent sequelae, including nAChR upregulation, probably endure for several days (Marks et al., 1985).</p><p>“Thus, subcellular pharmacokinetics readily explains dependence on smoked nicotine. Around the world at any time, several hundred million people have ingested tobacco within the past h; they retain nicotine in every organelle of every cell. In the ER and cis-Golgi of the small percentage of cells that contain α4β2 nAChRs, these levels activate the inside-out pathway > 50%, and therefore maintain one aspect of nicotine dependence.”</p><p><b><i>Quantitative aspects of reducing nicotine levels</i></b></p><p>Shivange at also noted, “the prominence of the inside-out pathway in nicotine dependence has prompted us to simulate the activation of the inside-out pathway, as well as the outside-in pathway, during use of modified tobacco or electronic nicotine delivery system (ENDS) (Table 1 of Shivange, 2019). United States FDA officials recently suggested decreasing the nicotine content of combustible tobacco. Our simulations show that a three-fold reduction would nonetheless provide > 40% average activation of the inside-out pathway; a ten-fold reduction (to 0.1 mg/cigarette) would reduce average activation to 20%. This conclusion would also hold for ingestion via an edlectronic nicotinic delivery system.”</p><p><b><i>Fast and slow metabolizers: the “elimination parameter”</i></b></p><p>The “standard habit” simulates a “typical” smoker (Benowitz et al., 1991), summarized by the “elimination parameter”. Increasing the “elimination parameter”, decreases the lifetime of nicotine in the blood / CSF / ER compartment. Polymorphisms in cytochrome P450 2A6, the enzyme that primarily metabolizes nicotine, lead to dramatic (up to 10-fold) differences in nicotine lifetime among people (Dempsey et al., 2004). Fast and slow metabolizers display differences in the frequency of smoking (Tanner et al., 2015). Future investigations must simulate the consequences of these variations for activation of the inside-out pathway.</p><p><b><i>Funding</i></b></p><p>This research and the development of this app was supported by grants from US National Institutes of Health (DA036061, DA037161, DA043829, GM123582, GM007616, MH061876, NS097362, and NS034407), the California Tobacco-Related Disease Research Project (23XT-0007), the California Institute for Regenerative Medicine (EDUC2-08398), the Brain and Behavior Research Foundation (NARSAD), the Howard Hughes Medical Institute, and the Della Martin Foundation. Edwin Chapman is an Investigator of the Howard Hughes Medical Institute.</p><p><b><i>References Cited</i></b></p><p>Benowitz, N.L., P. Jacob, 3rd, C. Denaro, and R. Jenkins. 1991. Stable isotope studies of nicotine kinetics and bioavailability. <i>Clin Pharmacol Ther.</i> 49:270-277.</p><p>Dempsey, D., P. Tutka, P. Jacob, 3rd, F. Allen, K. Schoedel, R.F. Tyndale, and N.L. Benowitz. 2004. Nicotine metabolite ratio as an index of cytochrome P450 2A6 metabolic activity. <i>Clin Pharmacol Ther.</i> 76:64-72.</p><p>Kuryatov, A., J. Luo, J. Cooper, and J. Lindstrom. 2005. Nicotine acts as a pharmacological chaperone to up-regulate human a4b2 acetylcholine receptors. <i>Mol Pharmacol.</i> 68:1839-1851.</p><p>Marks, M.J., J.A. Stitzel, and A.C. Collins. 1985. Time course study of the effects of chronic nicotine infusion on drug response and brain receptors. <i>J Pharmacol Exp Ther.</i> 235:619-628.</p><p>Rollema, H., L.K. Chambers, J.W. Coe, J. Glowa, R.S. Hurst, L.A. Lebel, Y. Lu, R.S. Mansbach, R.J. Mather, C.C. Rovetti, S.B. Sands, E. Schaeffer, D.W. Schulz, F.D. Tingley, 3rd, and K.E. Williams. 2007. Pharmacological profile of the α4β2 nicotinic acetylcholine receptor partial agonist varenicline, an effective smoking cessation aid. <i>Neuropharmacology.</i> 52.</p><p>Shivange, A.V., P.M. Borden, A.K. Muthusamy, A.L. Nichols, K. Bera, H. Bao, I. Bishara, J. Jeon, M.J. Mulcahy, B. Cohen, S.L. ORiordan, C. Kim, D.A. Dougherty, E.R. Chapman, J.S. Marvin, L.L. Looger, and H.A. Lester. 2019. Determining the pharmacokinetics of nicotinic drugs in the endoplasmic reticulum using biosensors. <i>J Gen Physiol 151:738-757.</i> <a href=""https://rupress.org/jgp/article/151/6/738/121017/Determining-the-pharmacokinetics-of-nicotinic"">https://rupress.org/jgp/article/151/6/738/121017/Determining-the-pharmacokinetics-of-nicotinic</a></p><p>Tanner, J.A., M.J. Chenoweth, and R.F. Tyndale. 2015. Pharmacogenetics of nicotine and associated smoking behaviors. <i>Current topics in behavioral neurosciences.</i> 23:37-86.</p>'';''';
            app.InputDoseTable = uitab(app.TabGroup);
            app.InputDoseTable.Title = 'Input Dose Table';
            app.UITable = uitable(app.InputDoseTable);
            app.UITable.ColumnName = {'TImestamp (s)'; 'Dose (mg)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.NumberofDosesEditFieldLabel = uilabel(app.InputDoseTable);
            app.NumberofDosesEditFieldLabel.Text = 'Number of Doses';
            app.NumberofDosesEditField = uieditfield(app.InputDoseTable, 'numeric');
            app.NumberofDosesEditField.Limits = [0 Inf];
            app.NumberofDosesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofDosesEditFieldValueChanged, true);
            app.ReadfromCSVButton = uibutton(app.InputDoseTable, 'push');
            app.ReadfromCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ReadfromCSVButtonPushed, true);
            app.ReadfromCSVButton.Text = 'Read from CSV';
            % Show the figure after all components are created
            app.NicotinePKPDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NicotineApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NicotinePKPDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NicotinePKPDUIFigure)
        end
    end
end