classdef VareniclineApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VareniclinePKPDUIFigure        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        SimulateButton                 matlab.ui.control.Button
        RecommendedDosingDefaultParametersButton  matlab.ui.control.Button
        VareniclineIngestionsTabletsPanel  matlab.ui.container.Panel
        DosingIntervalUnitField        matlab.ui.control.Label
        DosingAmountUnitField          matlab.ui.control.Label
        DurationofsimulationUnitField  matlab.ui.control.Label
        DurationofsimulationEditField_3Label  matlab.ui.control.Label
        DurationofsimulationField      matlab.ui.control.NumericEditField
        IngestionintervalLabel         matlab.ui.control.Label
        DosingIntervalField            matlab.ui.control.Spinner
        VareniclinedoseLabel           matlab.ui.control.Label
        DosingAmountSlider             matlab.ui.control.Slider
        DosingAmountField              matlab.ui.control.NumericEditField
        TotalingestionsSliderLabel     matlab.ui.control.Label
        TotalingestionsSlider          matlab.ui.control.Slider
        EditField                      matlab.ui.control.NumericEditField
        DosingAmountUnitField_3        matlab.ui.control.Label
        DosingAmountField_3            matlab.ui.control.NumericEditField
        DistributionandEliminationParametersPanel  matlab.ui.container.Panel
        kel1hrSliderLabel              matlab.ui.control.Label
        kel1hrSlider                   matlab.ui.control.Slider
        ka1hrSliderLabel               matlab.ui.control.Label
        ka1hrSlider                    matlab.ui.control.Slider
        kf1minSliderLabel              matlab.ui.control.Label
        kf1minSlider                   matlab.ui.control.Slider
        kr1minSliderLabel              matlab.ui.control.Label
        kr1minSlider                   matlab.ui.control.Slider
        FLabel                         matlab.ui.control.Label
        FSlider                        matlab.ui.control.Slider
        VdlitersSliderLabel            matlab.ui.control.Label
        VdlitersSlider                 matlab.ui.control.Slider
        Image3                         matlab.ui.control.Image
        DrugReceptorParametersPanel    matlab.ui.container.Panel
        ChaperoningkknMSliderLabel     matlab.ui.control.Label
        ChaperoningkknMSlider          matlab.ui.control.Slider
        ActivationkknMSliderLabel      matlab.ui.control.Label
        ActivationkknMSlider           matlab.ui.control.Slider
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        PlotsvsTimeTab                 matlab.ui.container.Tab
        Axes                           matlab.ui.control.UIAxes
        AxesConc                       matlab.ui.control.UIAxes
        Table                          matlab.ui.container.Tab
        SimDataTable                   matlab.ui.control.Table
        ExplanationTab                 matlab.ui.container.Tab
        HTML                           matlab.ui.control.HTML
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)

        Kel0(1,1) double        % original kel value in model
        Ka0(1,1) double        % original ka value in model
        Kf0(1,1) double        % original kf value in model
        Kr0(1,1) double        % original kr value in model
        F0(1,1) double        % original F value in model
        Duration0(1,1) double   % original duration of simulation
        Amount0(1,1) double     % original dosing amount
        Interval0(1,1) double   % original dosing interval
        TotalDaily0(1,1) double
        
        DoseTable cell         % daily dose to apply to simulate
        SimFun                  % exported simFunction 
        ConcColors       = [0.30,0.75,0.93;...
                            0.86,0.55,0.41;...
                            0.91,0.73,0.42]; % colors to plot concentrations
        ConcStyle        = {'-';'-';'-'};
    end

    methods (Access = private)
    
        function sd = simulateModel(app)
            % retrieve simulation settings from App
            kel = app.kel1hrSlider.Value;
            ka = app.ka1hrSlider.Value;
            kf = app.kf1minSlider.Value;
            kr = app.kr1minSlider.Value;
            F = app.FSlider.Value;
            stopTime = app.DurationofsimulationField.Value;
            %disp(kel)
            %disp(stopTime)
            
            %app.DoseTable.Amount = app.DosingAmountField.Value;
            app.DoseTable{1}.Amount = app.DosingAmountField.Value;
            %app.DoseTable{2}.Amount = app.DosingAmountField.Value;
            %app.DoseTable.Interval = app.DosingIntervalField.Value;
            app.DoseTable{1}.Interval = app.DosingIntervalField.Value * 60;
            %app.DoseTable{2}.Interval = app.DosingIntervalField.Value;
            %app.DoseTable.RepeatCount = app.EditField.Value - 1;
            app.DoseTable{1}.RepeatCount = app.EditField.Value - 1;
            %app.DoseTable{2}.RepeatCount = app.EditField.Value - 1;
            % simulate model using SimFunction
            %disp(app.DoseTable)
            %sd = app.SimFun([kel, 0.01, 0.042, 1.15, 0.01725, 0.9, 420], stopTime, app.DoseTable);
            %sd = app.SimFun([kel, 0.01, 0.042, 0.01725, 0.9], stopTime, app.DoseTable);
            %sd = app.SimFun([kel, 0.01, 0.042, 1.15, 0.9], stopTime, app.DoseTable);
            %sd = app.SimFun([0.026, 0.01, 0.042, 1.15, 0.9], stopTime, app.DoseTable); % THIS ONE WORKS
            sd = app.SimFun([kel, kf, kr, ka, F], stopTime, app.DoseTable);
            %sd = app.SimFun([kel, 0.1, 0.42, 1.15, 0.9], stopTime, app.DoseTable);
            %kel = 0.0435
            %sd = app.SimFun([kel, 0.1, 0.1, 1], stopTime, app.DoseTable);
            %disp(sd)
            
        end
        
        function updateApp(app)
            
            % TODO: update only lines in plots, not whole plots
            
            % create progress dialog
            d = uiprogressdlg(app.VareniclinePKPDUIFigure,'Message','Simulating model & updating App ...','Indeterminate','on');
            
            try
                % simulate model
                sd = simulateModel(app);
                
                % create table containing simulation results
                t = array2table([sd.time, sd.Data], 'VariableNames',[{'Time'}; sd.DataNames]);
                
                
                % update plots 
                xlim(app.Axes,'auto');
                xlim(app.AxesConc,'auto');
                ylim(app.Axes,'auto');
                ylim(app.AxesConc,'auto');
                
                semilogy(app.Axes, t.Time, t.("Plasma/ CSF/ ER Varenicline"), 'Color', [0.4940 0.1840 0.5560], 'Linewidth',2);
                hold(app.Axes,'on');
                plot(app.Axes, t.Time, t.("Sequestered Varenicline"), 'Color', [0.3010 0.7450 0.9330], 'Linewidth',2);
                
                app.Axes.YLim = [1, 1000];
                lg = legend(app.Axes, {'Plasma/ CSF/ ER Varenicline', 'Sequestered Varenicline'});
                lg.TextColor = ones(1,3)*0.4;
                lg.Box = 'off';
                hold(app.Axes,'off');
                
                plot(app.AxesConc, t.Time, t.("Fraction of Activated nAChR"), app.ConcStyle{1},'Linewidth',2,'Color', [0.4660 0.6740 0.1880]);
                hold(app.AxesConc,'on');
                plot(app.AxesConc, t.Time, t.("Fraction of Chaperoned nAChR"), app.ConcStyle{2},'Linewidth',2,'Color',[0.8500 0.3250 0.0980]);
                hold(app.AxesConc,'off');
                app.AxesConc.YLim = [0, 1];
                lh = legend(app.AxesConc,{'Activated nAChR channels','Chaperoned nAChR protein'});
                lh.TextColor = ones(1,3)*0.4;
                lh.Box = 'off';
                
                % update table
                app.SimDataTable.Data = t;
                units = [{'hours'}; cellfun(@(x) x.Units, sd.DataInfo, 'UniformOutput', false)];
                app.SimDataTable.ColumnName = string(t.Properties.VariableNames') + " (" + string(units) + ")";
                              
                % close progress dialog
                d.Indeterminate = 'off';
                d.Value = 1;
                
            catch e
                d.Indeterminate = 'off';
                d.Value = 1;
                d.Icon = 'error';
                d.Message = 'An error has occurred!';
                pause(2);
                rethrow(e)
            end
            
            close(d);
        end
        
        function setDefaultValues(app)
            app.kel1hrSlider.Value = app.Kel0;
            app.ka1hrSlider.Value = app.Ka0;
            app.kf1minSlider.Value = app.Kf0;
            app.kr1minSlider.Value = app.Kr0;
            app.FSlider.Value = app.F0;
            app.DurationofsimulationField.Value = app.Duration0;
            %disp(app.Amount0)
            app.DosingAmountField.Value = app.Amount0;
            app.DosingAmountSlider.Value = app.Amount0;
            
            %app.DosingAmountSlider_2.Value = app.Amount0 * 0.211267;
            app.DosingAmountField_3.Value = app.Amount0 * 0.211267;
            
            app.DosingIntervalField.Value = app.Interval0;
            %disp(app.TotalDaily0)
            app.TotalingestionsSlider.Value = app.TotalDaily0;
            app.EditField.Value = app.TotalDaily0;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HTML.HTMLSource = '<p><b><big>About the varenicline simulation</big></b></p><p><b><i>Legend to the simulation</i></b></p><p>This app simulates pharmacokinetics and pharmacodynamics of the smoking cessation drug, varenicline. At left, the pharmacokinetic / pharmacodynamic model, implemented in Matlab SimBiology. Individual parameters, structures, and dosing are presented in Supplemental Table 2 and in the Supplemental ZIP File of (Shivange et al., 2019).</p><p>The “Recommended Dosing” button reverts the simulation to the dosing recommended in the varenicline package insert: 0.5 mg of oral varenicline, each 12 h. Other parameters are based on the data of (Rollema et al., 2010) and (Faessel et. Al., 2006).</p><p>In the “plots vs time” tab: Top. Varenicline concentrations in the plasma / CSF / ER, and in the “sequestered” compartment. The “sequestered” compartment was termed the “peripheral compartment” by (Benowitz et al., 1991) in their study of nicotine, but that terminology is less preferable in discussions of the nervous system. Note the logarithmic [varenicline] scale.</p><p>Bottom. Effects on the inside-out and outside-in processes. Note that the “recommended dosing ” activates nAChR protein chaperoning (inside-out process) > 50%, but this activates nAChR channels (outside-in process) by a few percent at most.</p><p>This app is a slightly modified, interactive version of Figure 10 in (Shivange et al, 2019). The original simulations were performed by Janice Jeon, then a Caltech undergrad, in the Matlab SimBiology Toolbox. In 2020, Dr. Fulden Buyukozturk (The Mathworks) adapted the nicotine simulations to a Matlab Web App, with advice from Suresh Balakrishnama of The Mathworks. At Caltech, Alexander Wang, a Caltech undergrad, adapted the model to varenicline and modified the user interface further, based on suggestions from Henry Lester’s research group. The present version is running on a Unix server.</p><p><b><i>Lightning scientific summary: subcellular and macroscopic pharmacokinetics of nicotine and varenicline.</i></b></p><p>Modified from the abstract of (Shivange et al., 2019). Nicotine dependence is thought to arise in part because nicotine permeates into the endoplasmic reticulum (ER), where it binds to nicotinic receptors (nAChRs) and begins an “inside-out” pathway that leads to upregulation of nAChRs on the plasma membrane. However until 2019, the dynamics of nicotine or varenicline entry into the ER were not quantified. This problem was solved with a series of genetically-encoded fluorescent biosensors for nicotine, termed iNicSnFRs. In several cell types, we find that nicotine equilibrates in the ER within 10 s (possibly within 1 s) of extracellular application, and leaves as rapidly after removal from the extracellular solution. The [nicotine] in the ER is within two-fold of the extracellular value.</p><p>The biosensors also detect varenicline. Similar conclusions were reached for varenicline: this drug also permeates into the ER within seconds.</p><p>The app uses these data to run combined pharmacokinetic and pharmacodynamic simulations of varenicline. In the ER, the inside-out pathway begins when nicotine or varenicline becomes a stabilizing pharmacological chaperone for some nAChR subtypes, even at concentrations as low as ~ 10 nM. Such concentrations would persist in a person taking varenicline according to the recommended dosing.</p><p><b><i>Additional details: the outside-in and inside-out pathways during either smoking or varenicline treatment</i></b></p><p>The accompanying nicotine PK/PD app provides a summary of nicotine pharmacokinetics (condensed from the paper by Shivange et al., 2019). The present app’s simulations for varenicline incorporate an additional step appropriate to orally available drugs: rate-limiting entry into the blood from the digestive tract (Shivange et al., 2019).</p><p>Many fewer studies are available for varenicline than for nicotine.  Nonetheless, Shivange et al generated two insights important for understanding role of varenicline in the inside-out pathway. 1, The genetically encoded nicotine biosensors targeted to the ER reveal that varenicline appears in the ER within ≤ 10 s after it appears in the extracellular solution.  2, After this delay, the [varenicline] in the ER differs by less than two-fold from [varenicline] in the extracellular solution.</p><p>Because of the highly agonist-sensitive feature of pharmacological chaperoning, [varenicline] in the ER remains greater than the EC50 for pharmacological chaperoning during beginning a few h after the start of “recommended dosing”. The inside-out pathway then remains > 50% activated continually.</p><p><b><i>Does varenicline produce nicotine addiction; or does varenicline maintain nicotine addiction?</i></b></p><p>The simulations show that the “recommended dosing” produces levels of varenicline in the ER that could lead to pharmacological chaperoning. This explains the observations that varenicline also up-regulates α4β2 nAChRs (Turner et al., 2011; Marks et al., 2015; Govind et al., 2017).  We pursue the hypothesis that this up-regulation is necessary and sufficient for the early phases of nicotine dependence.</p><p>In the usual explanation for varenicline as a smoking cessation agent, even though it produces negligible activation of α4β2 nAChR channels (as verified in the simulations), it does desensitize these nAChRs, blocking the action of nicotine itself. The simulations do not directly model desensitization.</p><p>Our study supports the additional suggestion that the several-week taper regimen for varenicline also allows nicotine users to reverse up-regulation gradually. This, in turn, could help aspiring quitters to avoid craving and/or behavioral aspects of withdrawal. However, the persistence of up-regulated nAChRs could also cause some of the side effects of varenicline during the first days to weeks (Ashare et al., 2017). These side effects could underlie this drug’s suboptimal effectiveness in smoking cessation (Fagerström and Hughes, 2008).</p><p><b><i>Could you model your favorite drug (YFD) with this app?</i></b></p><p>This model is more general than the nicotine PK/PD app, because we incorporate a rate-limiting step from the digestive tract to the blood (ka).  This modification renders the model appropriate to many orally available drugs. The model also has controllable rate constants for entering and leaving (kf, kr respectively) a “sequestering compartment”; a rate constant for elimination (kel); and a fractional bioavailability (F).</p><p>The model also has two drug-receptor processes, both with a Hill coefficient of unity; but the user cannot vary the EC50 values. The user also cannot vary the volume of distribution. See Table S2 of (Shivange et al., 2019) for these parameters.</p><p>If you use this app for YFD, do let us know (lester at Caltech dot edu). This could help us with funding! If you would like to suggest changes, please do so.</p><p><b><i>Funding</i></b></p><p>This research and the development of this app was supported by grants from US National Institutes of Health (DA036061, DA037161, DA043829, GM123582, GM007616, MH061876, NS097362, and NS034407), the California Tobacco-Related Disease Research Project (23XT-0007), the California Institute for Regenerative Medicine (EDUC2-08398), the Brain and Behavior Research Foundation (NARSAD), the Howard Hughes Medical Institute, and the Della Martin Foundation. Edwin Chapman is an Investigator of the Howard Hughes Medical Institute.</p><p><b><i>References Cited</i></b></p><p>Ashare, R.L., C. Lerman, R.F. Tyndale, L.W. Hawk, T.P. George, P. Cinciripini, and R.A. Schnoll. 2017. Sleep Disturbance During Smoking Cessation: Withdrawal or Side Effect of Treatment? <i>J. Smok. Cessat</i>. 12:63–70.</p><p>Benowitz, N.L., P. Jacob, 3rd, C. Denaro, and R. Jenkins. 1991. Stable isotope studies of nicotine kinetics and bioavailability. <i>Clin Pharmacol Ther</i>. 49:270-277.</p><p>Fagerström, K., and J. Hughes. 2008. Varenicline in the treatment of tobacco dependence. Neuropsychiatr. Dis. Treat. 4:353–363. Govind, A.P., P. Vezina, and W.N. Green. 2009. Nicotine-induced upregulation of nicotinic receptors: underlying mechanisms and relevance to nicotine addiction. <i>Biochem. Pharmacol</i>. 78:756–765.</p><p>Marks, M.J., H.C. O’Neill, K.M. Wynalda-Camozzi, N.C. Ortiz, E.E. Simmons, C.A. Short, C.M. Butt, J.M. McIntosh, and S.R. Grady. 2015. Chronic treatment with varenicline changes expression of four nAChR binding sites in mice. <i>Neuropharmacology</i>. 99:142–155.</p><p>Rollema, H., L.K. Chambers, J.W. Coe, J. Glowa, R.S. Hurst, L.A. Lebel, Y. Lu, R.S. Mansbach, R.J. Mather, C.C. Rovetti, S.B. Sands, E. Schaeffer, D.W. Schulz, F.D. Tingley, 3rd, and K.E. Williams. 2007. Pharmacological profile of the α4β2 nicotinic acetylcholine receptor partial agonist varenicline, an effective smoking cessation aid. <i>Neuropharmacology</i>. 52.</p><p>Shivange, A.V., P.M. Borden, A.K. Muthusamy, A.L. Nichols, K. Bera, H. Bao, I. Bishara, J. Jeon, M.J. Mulcahy, B. Cohen, S.L. ORiordan, C. Kim, D.A. Dougherty, E.R. Chapman, J.S. Marvin, L.L. Looger, and H.A. Lester. 2019. Determining the pharmacokinetics of nicotinic drugs in the endoplasmic reticulum using biosensors. <i>J Gen Physiol 151:738-757</i>. <a href="https://rupress.org/jgp/article/151/6/738/121017/Determining-the-pharmacokinetics-of-nicotinic”>https://rupress.org/jgp/article/151/6/738/121017/Determining-the-pharmacokinetics-of-nicotinic</a></p><p>Turner, J.R., L.M. Castellano, and J.A. Blendy. 2011. Parallel anxiolytic-like effects and upregulation of neuronal nicotinic acetylcholine receptors following chronic nicotine and varenicline. <i>Nicotine Tob. Res</i>. 13:41–46.</p>';
            
            % save model and dose as App properties
            load('simFunction_Varenicline.mat', 'simFun', 'doseTable');
            app.SimFun    = simFun;
            app.DoseTable = {doseTable};
            
            
            % save original values to allow for resetting
            %app.Kel0 = app.SimFun.Parameters.Value(1);
            app.Kel0 = 0.026;
            app.Ka0 = 1.15;
            app.Kf0 = 0.01;
            app.Kr0 = 0.042;
            app.F0 = 0.9;
            %disp(app.Kel0)
            %app.Kon0 = app.SimFun.Parameters.Value(2);
            app.Duration0 = 72;
            %app.Rate0 = app.DoseTable.Rate;
            %app.Interval0 = app.DoseTable{1}.Interval;
            app.Interval0 = 12;
            %app.Amount0   = app.DoseTable.Amount(1);
            app.Amount0   = 2.37;
            %app.Amount0   = app.DoseTable{1}.Amount(1);
            app.TotalDaily0 = 6;
            
            % update UI with model values
            setDefaultValues(app);
            
            % run simulation and update app
            updateApp(app);
            
            % modify plot style
            %graystyle(app.Axes);
            %graystyle(app.AxesConc);
            
            % make tables and plots visible
            app.Axes.Visible = true;
            app.AxesConc.Visible = true; 
            app.SimDataTable.Visible = true;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.VareniclinePKPDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1050, 1050};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {529, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: kel1hrSlider
        function kel1hrSliderValueChanged(app, event)
            updateApp(app);
        end

        % Callback function
        function DosingIntervalFieldValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: DurationofsimulationField
        function DurationofsimulationFieldValueChanged(app, event)
            updateApp(app);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            updateApp(app);
        end

        % Value changed function: DosingAmountSlider
        function DosingAmountSliderValueChanged(app, event)
            app.DosingAmountField.Value = app.DosingAmountField.Value;
            %app.DosingAmountSlider_2.Value = app.DosingAmountField.Value * 0.211267;
            app.DosingAmountField_3.Value = app.DosingAmountField.Value * 0.211267;
            updateApp(app);
        end

        % Value changed function: DosingAmountField
        function DosingAmountFieldValueChanged(app, event)
            app.DosingAmountSlider.Value = app.DosingAmountField.Value;
            app.DosingAmountSlider_2.Value = app.DosingAmountField.Value * 0.211267;
            app.DosingAmountField_3.Value = app.DosingAmountField.Value * 0.211267;
            updateApp(app);
        end

        % Value changing function: DosingAmountSlider
        function DosingAmountSliderValueChanging(app, event)
            app.DosingAmountField.Value = round(event.Value);
            %app.DosingAmountField_2.Value = app.DosingAmountField.Value * 0.211267;
        end

        % Button pushed function: 
        % RecommendedDosingDefaultParametersButton
        function RecommendedDosingDefaultParametersButtonPushed(app, event)
            setDefaultValues(app);
            updateApp(app);
        end

        % Value changed function: TotalingestionsSlider
        function TotalingestionsSliderValueChanged(app, event)
            app.EditField.Value = round(event.Value);
            updateApp(app)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.TotalingestionsSlider.Value = app.EditField.Value;
            updateApp(app)
        end

        % Value changing function: TotalingestionsSlider
        function TotalingestionsSliderValueChanging(app, event)
            app.EditField.Value = round(event.Value);
            
        end

        % Callback function
        function DosingAmountSlider_2ValueChanged(app, event)
            value = app.DosingAmountSlider_2.Value;
            app.DosingAmountField.Value = value / 0.211267;
            app.DosingAmountSlider.Value = value / 0.211267;
            app.DosingAmountField_3.Value = value;
            updateApp(app);
        end

        % Value changed function: DosingAmountField_3
        function DosingAmountField_3ValueChanged(app, event)
            value = app.DosingAmountField_3.Value;
            app.DosingAmountField.Value = value / 0.211267;
            %app.DosingAmountSlider_2.Value = value;
            app.DosingAmountSlider.Value = value / 0.211267;
            updateApp(app);
        end

        % Value changed function: ka1hrSlider
        function ka1hrSliderValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: kf1minSlider
        function kf1hrSliderValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: kr1minSlider
        function kr1hrSliderValueChanged(app, event)
            updateApp(app);
        end

        % Value changed function: FSlider
        function FSliderValueChanged(app, event)
            updateApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VareniclinePKPDUIFigure = uifigure('Visible', 'off');
            app.VareniclinePKPDUIFigure.Name = 'Varenicline PKPD';
            app.VareniclinePKPDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.VareniclinePKPDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = 'bigvarendiagram, rev_5-dig_icon copy.svg';
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Icon = 'Run_24.png';
            app.SimulateButton.Text = 'Simulate';
            app.RecommendedDosingDefaultParametersButton = uibutton(app.LeftPanel, 'push');
            app.RecommendedDosingDefaultParametersButton.ButtonPushedFcn = createCallbackFcn(app, @RecommendedDosingDefaultParametersButtonPushed, true);
            app.RecommendedDosingDefaultParametersButton.Text = {'Recommended Dosing;'; 'Default Parameters'};
            app.VareniclineIngestionsTabletsPanel = uipanel(app.LeftPanel);
            app.VareniclineIngestionsTabletsPanel.Title = 'Varenicline Ingestions: Tablets';
            app.DosingIntervalUnitField = uilabel(app.VareniclineIngestionsTabletsPanel);
            app.DosingIntervalUnitField.Text = 'hour';
            app.DosingAmountUnitField = uilabel(app.VareniclineIngestionsTabletsPanel);
            app.DosingAmountUnitField.Text = 'μmole';
            app.DurationofsimulationUnitField = uilabel(app.VareniclineIngestionsTabletsPanel);
            app.DurationofsimulationUnitField.Text = 'hour';
            app.DurationofsimulationEditField_3Label = uilabel(app.VareniclineIngestionsTabletsPanel);
            app.DurationofsimulationEditField_3Label.Text = 'Duration of simulation';
            app.DurationofsimulationField = uieditfield(app.VareniclineIngestionsTabletsPanel, 'numeric');
            app.DurationofsimulationField.UpperLimitInclusive = 'off';
            app.DurationofsimulationField.Limits = [1 Inf];
            app.DurationofsimulationField.ValueChangedFcn = createCallbackFcn(app, @DurationofsimulationFieldValueChanged, true);
            app.DurationofsimulationField.Value = 40;
            app.IngestionintervalLabel = uilabel(app.VareniclineIngestionsTabletsPanel);
            app.IngestionintervalLabel.Text = 'Ingestion interval';
            app.DosingIntervalField = uispinner(app.VareniclineIngestionsTabletsPanel);
            app.DosingIntervalField.Limits = [0 24];
            app.DosingIntervalField.RoundFractionalValues = 'on';
            app.DosingIntervalField.Value = 12;
            app.VareniclinedoseLabel = uilabel(app.VareniclineIngestionsTabletsPanel);
            app.VareniclinedoseLabel.Text = 'Varenicline dose';
            app.DosingAmountSlider = uislider(app.VareniclineIngestionsTabletsPanel);
            app.DosingAmountSlider.Limits = [0 10];
            app.DosingAmountSlider.MajorTicks = [0 2.5 5 7.5 10];
            app.DosingAmountSlider.MajorTickLabels = {'0', '2.5', '5', '7.5', '10'};
            app.DosingAmountSlider.ValueChangedFcn = createCallbackFcn(app, @DosingAmountSliderValueChanged, true);
            app.DosingAmountSlider.ValueChangingFcn = createCallbackFcn(app, @DosingAmountSliderValueChanging, true);
            app.DosingAmountSlider.Value = 3.5;
            app.DosingAmountField = uieditfield(app.VareniclineIngestionsTabletsPanel, 'numeric');
            app.DosingAmountField.Limits = [0 10];
            app.DosingAmountField.ValueDisplayFormat = '%.1f';
            app.DosingAmountField.ValueChangedFcn = createCallbackFcn(app, @DosingAmountFieldValueChanged, true);
            app.DosingAmountField.Value = 3.5;
            app.TotalingestionsSliderLabel = uilabel(app.VareniclineIngestionsTabletsPanel);
            app.TotalingestionsSliderLabel.Text = 'Total ingestions';
            app.TotalingestionsSlider = uislider(app.VareniclineIngestionsTabletsPanel);
            app.TotalingestionsSlider.Limits = [0 6];
            app.TotalingestionsSlider.MajorTicks = [0 2 4 6];
            app.TotalingestionsSlider.ValueChangedFcn = createCallbackFcn(app, @TotalingestionsSliderValueChanged, true);
            app.TotalingestionsSlider.ValueChangingFcn = createCallbackFcn(app, @TotalingestionsSliderValueChanging, true);
            app.TotalingestionsSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.TotalingestionsSlider.Value = 6;
            app.EditField = uieditfield(app.VareniclineIngestionsTabletsPanel, 'numeric');
            app.EditField.Limits = [0 6];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 6;
            app.DosingAmountUnitField_3 = uilabel(app.VareniclineIngestionsTabletsPanel);
            app.DosingAmountUnitField_3.Text = 'mg';
            app.DosingAmountField_3 = uieditfield(app.VareniclineIngestionsTabletsPanel, 'numeric');
            app.DosingAmountField_3.Limits = [0 10];
            app.DosingAmountField_3.ValueDisplayFormat = '%.1f';
            app.DosingAmountField_3.ValueChangedFcn = createCallbackFcn(app, @DosingAmountField_3ValueChanged, true);
            app.DosingAmountField_3.Value = 1;
            app.DistributionandEliminationParametersPanel = uipanel(app.LeftPanel);
            app.DistributionandEliminationParametersPanel.Title = 'Distribution and Elimination Parameters';
            app.kel1hrSliderLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.kel1hrSliderLabel.Text = 'kel (1/hr)';
            app.kel1hrSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.kel1hrSlider.Limits = [0 0.1];
            app.kel1hrSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.kel1hrSlider.MajorTickLabels = {'0', '0.02', '0.04', '0.06', '0.08', '0.1'};
            app.kel1hrSlider.ValueChangedFcn = createCallbackFcn(app, @kel1hrSliderValueChanged, true);
            app.kel1hrSlider.Value = 0.026;
            app.ka1hrSliderLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.ka1hrSliderLabel.Text = 'ka (1/hr)';
            app.ka1hrSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.ka1hrSlider.Limits = [0 2];
            app.ka1hrSlider.MajorTicks = [0 0.4 0.8 1.2 1.6 2];
            app.ka1hrSlider.MajorTickLabels = {'0', '0.4', '0.8', '1.2', '1.6', '2'};
            app.ka1hrSlider.ValueChangedFcn = createCallbackFcn(app, @ka1hrSliderValueChanged, true);
            app.ka1hrSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1 1.05 1.1 1.15 1.2 1.25 1.3 1.35 1.4 1.45 1.5 1.55 1.6 1.65 1.7 1.75 1.8 1.85 1.9 1.95 2];
            app.ka1hrSlider.Value = 1.15;
            app.kf1minSliderLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.kf1minSliderLabel.Text = 'kf (1/min)';
            app.kf1minSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.kf1minSlider.Limits = [0 0.1];
            app.kf1minSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.kf1minSlider.MajorTickLabels = {'0', '0.02', '0.04', '0.06', '0.08', '0.1'};
            app.kf1minSlider.ValueChangedFcn = createCallbackFcn(app, @kf1hrSliderValueChanged, true);
            app.kf1minSlider.Value = 0.01;
            app.kr1minSliderLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.kr1minSliderLabel.Text = 'kr (1/min)';
            app.kr1minSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.kr1minSlider.Limits = [0 0.1];
            app.kr1minSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.kr1minSlider.MajorTickLabels = {'0', '0.02', '0.04', '0.06', '0.08', '0.1'};
            app.kr1minSlider.ValueChangedFcn = createCallbackFcn(app, @kr1hrSliderValueChanged, true);
            app.kr1minSlider.Value = 0.042;
            app.FLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.FLabel.Text = 'F';
            app.FSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.FSlider.Limits = [0 1];
            app.FSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.FSlider.MajorTickLabels = {'0.0', '0.2', '0.4', '0.6', '0.8', '1.0'};
            app.FSlider.ValueChangedFcn = createCallbackFcn(app, @FSliderValueChanged, true);
            app.FSlider.Value = 0.9;
            app.VdlitersSliderLabel = uilabel(app.DistributionandEliminationParametersPanel);
            app.VdlitersSliderLabel.Text = 'Vd (liters)';
            app.VdlitersSlider = uislider(app.DistributionandEliminationParametersPanel);
            app.VdlitersSlider.Limits = [0 500];
            app.VdlitersSlider.MajorTicks = [0 100 200 300 400 500];
            app.VdlitersSlider.MajorTickLabels = {'0', '100', '200', '300', '400', '500'};
            app.VdlitersSlider.Value = 0.026;
            app.Image3 = uiimage(app.LeftPanel);
            app.Image3.ImageSource = 'Caltech_Logo.png';
            app.DrugReceptorParametersPanel = uipanel(app.LeftPanel);
            app.DrugReceptorParametersPanel.Title = 'Drug-Receptor Parameters';
            app.ChaperoningkknMSliderLabel = uilabel(app.DrugReceptorParametersPanel);
            app.ChaperoningkknMSliderLabel.Text = 'Chaperoning k-/k+ (nM)';
            app.ChaperoningkknMSlider = uislider(app.DrugReceptorParametersPanel);
            app.ChaperoningkknMSlider.Limits = [0 20];
            app.ChaperoningkknMSlider.MajorTicks = [0 4 8 12 16 20];
            app.ChaperoningkknMSlider.MajorTickLabels = {'0', '4', '8', '12', '16', '20'};
            app.ChaperoningkknMSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.ChaperoningkknMSlider.Value = 10;
            app.ActivationkknMSliderLabel = uilabel(app.DrugReceptorParametersPanel);
            app.ActivationkknMSliderLabel.Text = 'Activation k-/k+ (nM)';
            app.ActivationkknMSlider = uislider(app.DrugReceptorParametersPanel);
            app.ActivationkknMSlider.Limits = [0 10000];
            app.ActivationkknMSlider.MajorTicks = [0 2000 4000 6000 8000 10000];
            app.ActivationkknMSlider.MajorTickLabels = {'0', '2000', '4000', '6000', '8000', '10000'};
            app.ActivationkknMSlider.MinorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000];
            app.ActivationkknMSlider.Value = 3000;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotsvsTimeTab = uitab(app.TabGroup);
            app.PlotsvsTimeTab.Title = 'Plots vs. Time';
            app.Axes = uiaxes(app.PlotsvsTimeTab);
            xlabel(app.Axes, 'Time (hours)')
            ylabel(app.Axes, 'Concentration (nM)')
            app.Axes.XTick = [0 24 48 72];
            app.Axes.XTickLabel = {'0'; '24'; '48'; '72'};
            app.AxesConc = uiaxes(app.PlotsvsTimeTab);
            xlabel(app.AxesConc, 'Time (hours)')
            ylabel(app.AxesConc, 'Fraction')
            app.AxesConc.XTick = [0 24 48 72];
            app.AxesConc.XTickLabel = {'0'; '24'; '48'; '72'};
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.SimDataTable = uitable(app.Table);
            app.SimDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SimDataTable.RowName = {};
            app.ExplanationTab = uitab(app.TabGroup);
            app.ExplanationTab.Title = 'Explanation';
            app.HTML = uihtml(app.ExplanationTab);
            app.HTML.HTMLSource = '{''<p><b><big>About the varenicline simulation</big></b></p><p><b><i>Legend to the simulation</i></b></p><p>This app simulates pharmacokinetics and pharmacodynamics of the smoking cessation drug, varenicline. At left, the pharmacokinetic / pharmacodynamic model, implemented in Matlab SimBiology. Individual parameters, structures, and dosing are presented in Supplemental Table 2 and in the Supplemental ZIP File of (Shivange et al., 2019).</p><p>The “Recommended Dosing” button reverts the simulation to the dosing recommended in the varenicline package insert: 0.5 mg of oral varenicline, each 12 h. Other parameters are based on the data of (Rollema et al., 2010) and (Faessel et. Al., 2006).</p><p>In the “plots vs time” tab: Top. Varenicline concentrations in the plasma / CSF / ER, and in the “sequestered” compartment. The “sequestered” compartment was termed the “peripheral compartment” by (Benowitz et al., 1991) in their study of nicotine, but that terminology is less preferable in discussions of the nervous system. Note the logarithmic [varenicline] scale.</p><p>Bottom. Effects on the inside-out and outside-in processes. Note that the “recommended dosing ” activates nAChR protein chaperoning (inside-out process) > 50%, but this activates nAChR channels (outside-in process) by a few percent at most.</p><p>This app is a slightly modified, interactive version of Figure 10 in (Shivange et al, 2019). The original simulations were performed by Janice Jeon, then a Caltech undergrad, in the Matlab SimBiology Toolbox. In 2020, Dr. Fulden Buyukozturk (The Mathworks) adapted the nicotine simulations to a Matlab Web App, with advice from Suresh Balakrishnama of The Mathworks. At Caltech, Alexander Wang, a Caltech undergrad, adapted the model to varenicline and modified the user interface further, based on suggestions from Henry Lester’s research group. The present version is running on a Unix server.</p><p><b><i>Lightning scientific summary: subcellular and macroscopic pharmacokinetics of nicotine and varenicline.</i></b></p><p>Modified from the abstract of (Shivange et al., 2019). Nicotine dependence is thought to arise in part because nicotine permeates into the endoplasmic reticulum (ER), where it binds to nicotinic receptors (nAChRs) and begins an “inside-out” pathway that leads to upregulation of nAChRs on the plasma membrane. However until 2019, the dynamics of nicotine or varenicline entry into the ER were not quantified. This problem was solved with a series of genetically-encoded fluorescent biosensors for nicotine, termed iNicSnFRs. In several cell types, we find that nicotine equilibrates in the ER within 10 s (possibly within 1 s) of extracellular application, and leaves as rapidly after removal from the extracellular solution. The [nicotine] in the ER is within two-fold of the extracellular value.</p><p>The biosensors also detect varenicline. Similar conclusions were reached for varenicline: this drug also permeates into the ER within seconds.</p><p>The app uses these data to run combined pharmacokinetic and pharmacodynamic simulations of varenicline. In the ER, the inside-out pathway begins when nicotine or varenicline becomes a stabilizing pharmacological chaperone for some nAChR subtypes, even at concentrations as low as ~ 10 nM. Such concentrations would persist in a person taking varenicline according to the recommended dosing.</p><p><b><i>Additional details: the outside-in and inside-out pathways during either smoking or varenicline treatment</i></b></p><p>The accompanying nicotine PK/PD app provides a summary of nicotine pharmacokinetics (condensed from the paper by Shivange et al., 2019). The present app’s simulations for varenicline incorporate an additional step appropriate to orally available drugs: rate-limiting entry into the blood from the digestive tract (Shivange et al., 2019).</p><p>Many fewer studies are available for varenicline than for nicotine.  Nonetheless, Shivange et al generated two insights important for understanding role of varenicline in the inside-out pathway. 1, The genetically encoded nicotine biosensors targeted to the ER reveal that varenicline appears in the ER within ≤ 10 s after it appears in the extracellular solution.  2, After this delay, the [varenicline] in the ER differs by less than two-fold from [varenicline] in the extracellular solution.</p><p>Because of the highly agonist-sensitive feature of pharmacological chaperoning, [varenicline] in the ER remains greater than the EC50 for pharmacological chaperoning during beginning a few h after the start of “recommended dosing”. The inside-out pathway then remains > 50% activated continually.</p><p><b><i>Does varenicline produce nicotine addiction; or does varenicline maintain nicotine addiction?</i></b></p><p>The simulations show that the “recommended dosing” produces levels of varenicline in the ER that could lead to pharmacological chaperoning. This explains the observations that varenicline also up-regulates α4β2 nAChRs (Turner et al., 2011; Marks et al., 2015; Govind et al., 2017).  We pursue the hypothesis that this up-regulation is necessary and sufficient for the early phases of nicotine dependence.</p><p>In the usual explanation for varenicline as a smoking cessation agent, even though it produces negligible activation of α4β2 nAChR channels (as verified in the simulations), it does desensitize these nAChRs, blocking the action of nicotine itself. The simulations do not directly model desensitization.</p><p>Our study supports the additional suggestion that the several-week taper regimen for varenicline also allows nicotine users to reverse up-regulation gradually. This, in turn, could help aspiring quitters to avoid craving and/or behavioral aspects of withdrawal. However, the persistence of up-regulated nAChRs could also cause some of the side effects of varenicline during the first days to weeks (Ashare et al., 2017). These side effects could underlie this drug’s suboptimal effectiveness in smoking cessation (Fagerström and Hughes, 2008).</p><p><b><i>Could you model your favorite drug (YFD) with this app?</i></b></p><p>This model is more general than the nicotine PK/PD app, because we incorporate a rate-limiting step from the digestive tract to the blood (ka).  This modification renders the model appropriate to many orally available drugs. The model also has controllable rate constants for entering and leaving (kf, kr respectively) a “sequestering compartment”; a rate constant for elimination (kel); and a fractional bioavailability (F).</p><p>The model also has two drug-receptor processes, both with a Hill coefficient of unity; but the user cannot vary the EC50 values. The user also cannot vary the volume of distribution. See Table S2 of (Shivange et al., 2019) for these parameters.</p><p>If you use this app for YFD, do let us know (lester at Caltech dot edu). This could help us with funding! If you would like to suggest changes, please do so.</p><p><b><i>Funding</i></b></p><p>This research and the development of this app was supported by grants from US National Institutes of Health (DA036061, DA037161, DA043829, GM123582, GM007616, MH061876, NS097362, and NS034407), the California Tobacco-Related Disease Research Project (23XT-0007), the California Institute for Regenerative Medicine (EDUC2-08398), the Brain and Behavior Research Foundation (NARSAD), the Howard Hughes Medical Institute, and the Della Martin Foundation. Edwin Chapman is an Investigator of the Howard Hughes Medical Institute.</p><p><b><i>References Cited</i></b></p><p>Ashare, R.L., C. Lerman, R.F. Tyndale, L.W. Hawk, T.P. George, P. Cinciripini, and R.A. Schnoll. 2017. Sleep Disturbance During Smoking Cessation: Withdrawal or Side Effect of Treatment? <i>J. Smok. Cessat</i>. 12:63–70.</p><p>Benowitz, N.L., P. Jacob, 3rd, C. Denaro, and R. Jenkins. 1991. Stable isotope studies of nicotine kinetics and bioavailability. <i>Clin Pharmacol Ther</i>. 49:270-277.</p><p>Fagerström, K., and J. Hughes. 2008. Varenicline in the treatment of tobacco dependence. Neuropsychiatr. Dis. Treat. 4:353–363. Govind, A.P., P. Vezina, and W.N. Green. 2009. Nicotine-induced upregulation of nicotinic receptors: underlying mechanisms and relevance to nicotine addiction. <i>Biochem. Pharmacol</i>. 78:756–765.</p><p>Marks, M.J., H.C. O’Neill, K.M. Wynalda-Camozzi, N.C. Ortiz, E.E. Simmons, C.A. Short, C.M. Butt, J.M. McIntosh, and S.R. Grady. 2015. Chronic treatment with varenicline changes expression of four nAChR binding sites in mice. <i>Neuropharmacology</i>. 99:142–155.</p><p>Rollema, H., L.K. Chambers, J.W. Coe, J. Glowa, R.S. Hurst, L.A. Lebel, Y. Lu, R.S. Mansbach, R.J. Mather, C.C. Rovetti, S.B. Sands, E. Schaeffer, D.W. Schulz, F.D. Tingley, 3rd, and K.E. Williams. 2007. Pharmacological profile of the α4β2 nicotinic acetylcholine receptor partial agonist varenicline, an effective smoking cessation aid. <i>Neuropharmacology</i>. 52.</p><p>Shivange, A.V., P.M. Borden, A.K. Muthusamy, A.L. Nichols, K. Bera, H. Bao, I. Bishara, J. Jeon, M.J. Mulcahy, B. Cohen, S.L. ORiordan, C. Kim, D.A. Dougherty, E.R. Chapman, J.S. Marvin, L.L. Looger, and H.A. Lester. 2019. Determining the pharmacokinetics of nicotinic drugs in the endoplasmic reticulum using biosensors. <i>J Gen Physiol 151:738-757</i>. <a href="https://rupress.org/jgp/article/151/6/738/121017/Determining-the-pharmacokinetics-of-nicotinic”>https://rupress.org/jgp/article/151/6/738/121017/Determining-the-pharmacokinetics-of-nicotinic</a></p><p>Turner, J.R., L.M. Castellano, and J.A. Blendy. 2011. Parallel anxiolytic-like effects and upregulation of neuronal nicotinic acetylcholine receptors following chronic nicotine and varenicline. <i>Nicotine Tob. Res</i>. 13:41–46.</p>''}';
            % Show the figure after all components are created
            app.VareniclinePKPDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VareniclineApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VareniclinePKPDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VareniclinePKPDUIFigure)
        end
    end
end